classdef TableDataApp4Ecg2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EcgErrorAnalyisisUIFigure  matlab.ui.Figure
        BPMDisplayDropDown         matlab.ui.control.DropDown
        BPMDisplayDropDownLabel    matlab.ui.control.Label
        DuplicateRowButton         matlab.ui.control.Button
        EvaluateButton             matlab.ui.control.Button
        RowIndexSpinner            matlab.ui.control.Spinner
        RowIndexSpinnerLabel       matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        UIAxes_6                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            tableRow = app.UITable.DisplayData;            
            baseDirPath = pwd;
            while ~isfolder(fullfile(baseDirPath,'data'))
                [baseDirPath,~,~] = fileparts(baseDirPath);
            end

            % add the code in the entire directory 
            % (change path if code is in a different directory)
            addpath(genpath(baseDirPath))
            
            % load the file paths
            paths = loadpaths(baseDirPath,'saveFile','saveNewFile');

            dirPath = fullfile(paths.derivativesDirPath,"ecg_find_errors");

            readFilePath = fullfile(dirPath,'ecg_time_corrections.xls');

            bigTable = readtable(readFilePath);

            vars = bigTable.Properties.VariableNames;


            % Plot the original data
            currSub = tableRow.currSubs;
            currTime = tableRow.currTimes;

            % Get new data
            subTable = bigTable(bigTable.currSubs==currSub,:);

            [ibiData,ibiTime] = readecg(paths,currSub,'timeRef','ecg');
            [rawDataCh1,rawTime] = readecg(paths,currSub,'output','raw','chanNum',1,'timeRef','ecg');
            [rawDataCh2,~] = readecg(paths,currSub,'output','raw','chanNum',2,'timeRef','ecg');
            
            origIbiData = ibiData;
            origIbiTime = ibiTime;


            acceptDiff = 0.1;
                                        
            for i=1:length(subTable.unreliableTimes)
                if ~isnan(subTable.unreliableTimes(i))
                    [diffUnrel,toUnrelIdx] = min(abs(ibiTime - subTable.unreliableTimes(i)));
                    if diffUnreal > acceptDiff
                        [ibiTime,sortIdx] = sort([ibiTime;subTable.unreliableTimes(i)]);
                        ibiCombined = [ibiData,NaN];
                        ibiData = ibiCombined(sortIdx);
                    else
                        ibiData(toUnrelIdx) = NaN;
                    end
                end   
             end
%             if ~isnan(tableRow.unreliableTimes)
%                 
%                 for i=1:length(tableRow.unreliableTimes)
%                     [diffUnrel,toUnrelIdx] = min(abs(ibiTime - tableRow.unreliableTimes(i)));
%                     if diffUnreal > acceptDiff
%                         [ibiTime,sortIdx] = sort([ibiTime;tableRow.unreliableTimes(i)]);
%                         ibiCombined = [ibiData,NaN];
%                         ibiData = ibiCombined(sortIdx);
%                     else
%                         ibiData(toUnrelIdx) = NaN;
%                     end
%                 end   
%              end

            for i=1:length(subTable.toRemTimes)
                if ~isnan(subTable.toRemTimes(i))
                    [~,toRemIdx] = min(abs(ibiTime - subTable.toRemTimes(i)));
                    ibiTime(toRemIdx) = [];
                    if toRemIdx > 1
                        ibiData(toRemIdx) = [];
                        ibiData(toRemIdx) = (ibiTime(toRemIdx)-ibiTime(toRemIdx-1))*1000;
                        ibiData(toRemIdx - 1) = (ibiTime(toRemIdx-1)-ibiTime(toRemIdx-2))*1000;
                    else
                        ibiData = ibiData(2:end);
                    end
                end
            end

%             if ~isnan(tableRow.toRemTimes)
%                 [~,toRemIdx] = min(abs(ibiTime - tableRow.toRemTimes));
%                 ibiTime(toRemIdx) = [];
%                 if toRemIdx > 1
%                     ibiData(toRemIdx) = [];
%                     ibiData(toRemIdx - 1) = (ibiTime(toRemIdx)-ibiTime(toRemIdx-1))*1000;
%                 else
%                     ibiData = ibiData(2:end);
%                 end
%             end


           for i=1:length(subTable.toAddTimes)
                if ~isnan(subTable.toAddTimes(i))
                    [ibiTime, sortIdx] = sort([ibiTime;subTable.toAddTimes(i)]);
                    [~,sIdx] = max(sortIdx);
                    ibiData(sIdx+1:end+1) = ibiData(sIdx:end);
                    %if ~isnan(ibiData(sIdx+1)) & ~isnan(ibiData(sIdx))
                        ibiData(sIdx+1) = (ibiTime(sIdx+1)-ibiTime(sIdx))*1000;
                    %end
                    %if ~isnan(ibiData(sIdx-1)) & ~isnan(ibiData(sIdx))
                        ibiData(sIdx) = (ibiTime(sIdx)-ibiTime(sIdx-1))*1000;
                    %end
                    %if ~isnan(ibiData(sIdx-1)) & ~isnan(ibiData(sIdx-2))
                        ibiData(sIdx-1) = (ibiTime(sIdx-1)-ibiTime(sIdx-2))*1000;
                    %end
                end
                % ibiData = [ibiData(1);diff(ibiTime)*1000];
                % ibiData = tableRow.toAddTimes;
                
            end

%             if ~isnan(tableRow.toAddTimes)
%                 
%                 %[~,ia] = setdiff(ibiTime,ibiTimeBeforeAdd);
%                 for i=1:length(tableRow.toAddTimes)
%                     [ibiTime, sortIdx] = sort([ibiTime;tableRow.toAddTimes(i)]);
%                     [~,sIdx] = max(sortIdx);
%                     ibiData(sIdx+1:end+1) = ibiData(sIdx:end);
%                     if ~isnan(ibiData(sIdx+1)) & ~isnan(ibiData(sIdx))
%                         ibiData(sIdx+1) = (ibiTime(sIdx+1)-ibiTime(sIdx))*1000;
%                     end
%                     if ~isnan(ibiData(sIdx-1)) & ~isnan(ibiData(sIdx))
%                         ibiData(sIdx) = (ibiTime(sIdx)-ibiTime(sIdx-1))*1000;
%                     end
%                     if ~isnan(ibiData(sIdx-1)) & ~isnan(ibiData(sIdx-2))
%                         ibiData(sIdx-1) = (ibiTime(sIdx-1)-ibiTime(sIdx-2))*1000;
%                     end
%                 end
%                 % ibiData = [ibiData(1);diff(ibiTime)*1000];
%                 % ibiData = tableRow.toAddTimes;
%                 
%             end
            
            ecgFs = 1/median(diff(rawTime));

            xlimSubPlots = [currTime-10,currTime+10];
            
            if contains(app.BPMDisplayDropDown.Value,'Diff')
                ibiData = [ibiData;diff(ibiTime)];
            end

            plot(app.UIAxes_4,ibiTime,60./(ibiData./1000))
            hold(app.UIAxes_4,'on')
            scatter(app.UIAxes_4,currTime,60/(origIbiData(origIbiTime==currTime)/1000),'LineWidth',3)
            xlim(app.UIAxes_4,xlimSubPlots)
            hold(app.UIAxes_4,'off')

            plot(app.UIAxes_5,rawTime,rawDataCh1)
            hold(app.UIAxes_5,'on')
            scatter(app.UIAxes_5,ibiTime,rawDataCh1(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            hold(app.UIAxes_5,'on')
            scatter(app.UIAxes_5,currTime,rawDataCh1(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_5,'off')
            xlim(app.UIAxes_5,xlimSubPlots)

            plot(app.UIAxes_6,rawTime,rawDataCh2)
            hold(app.UIAxes_6,'on')
            scatter(app.UIAxes_6,ibiTime,rawDataCh2(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            hold(app.UIAxes_6,'on')
            scatter(app.UIAxes_6,currTime,rawDataCh2(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_6,'off')
            xlim(app.UIAxes_6,xlimSubPlots)
            % Plot modified data 
            %x2 = t.Age;
            %y2 = t.BloodPressure(:,2);
            %plot(app.UIAxes2,x2,y2,'-o');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
                        
            % Sort the data by age
            % t = sortrows(t,'Age');
            
            % Combine Systolic and Diastolic into one variable
            %t.BloodPressure = [t.Systolic t.Diastolic];
            %t.Systolic = [];
            %t.Diastolic = [];
            
            % Convert SelfAssessedHealthStatus to categorical
            %cats = categorical(t.SelfAssessedHealthStatus,{'Poor','Fair','Good','Excellent'});
            %t.SelfAssessedHealthStatus = cats;
            
            % Rearrange columns
            %t = t(:,[1 4 3 2]);
            
            
            baseDirPath = pwd;
            while ~isfolder(fullfile(baseDirPath,'data'))
                [baseDirPath,~,~] = fileparts(baseDirPath);
            end

            % add the code in the entire directory 
            % (change path if code is in a different directory)
            addpath(genpath(baseDirPath))
            
            % load the file paths
            paths = loadpaths(baseDirPath,'saveFile','saveNewFile');
            dirPath = fullfile(paths.derivativesDirPath,"ecg_find_errors");

            readFilePath = fullfile(dirPath,'ecg_time_corrections.xls');

            bigTable = readtable(readFilePath);
            vars = bigTable.Properties.VariableNames;

            % Select a subset of the table array
            tableRow = bigTable(app.RowIndexSpinner.Value,vars);
            
            % Add data to the Table UI Component
            app.UITable.Data = tableRow;

            % Plot the original data
            currSub = tableRow.currSubs;
            currTime = tableRow.currTimes;
            [ibiData,ibiTime] = readecg(paths,currSub,'timeRef','ecg');
            [rawDataCh1,rawTime] = readecg(paths,currSub,'output','raw','chanNum',1,'timeRef','ecg');
            [rawDataCh2,~] = readecg(paths,currSub,'output','raw','chanNum',2,'timeRef','ecg');
            %%
            ecgFs = 1/median(diff(rawTime));

            xlimSubPlots = [currTime-10,currTime+10];

            plot(app.UIAxes,ibiTime,60./(ibiData./1000))
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,currTime,60/(ibiData(ibiTime==currTime)/1000),'LineWidth',3)
            xlim(app.UIAxes,xlimSubPlots)
            hold(app.UIAxes,'off')

            plot(app.UIAxes_2,rawTime,rawDataCh1)
            hold(app.UIAxes_2,'on')
            scatter(app.UIAxes_2,ibiTime,rawDataCh1(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            % hold(app.UIAxes_2,'on')
            % scatter(app.UIAxes_2,currTime,rawDataCh1(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_2,'off')
            xlim(app.UIAxes_2,xlimSubPlots)

            plot(app.UIAxes_3,rawTime,rawDataCh2)
            hold(app.UIAxes_3,'on')
            scatter(app.UIAxes_3,ibiTime,rawDataCh2(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            % hold(app.UIAxes_3,'on')
            % scatter(app.UIAxes_3,currTime,rawDataCh2(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_3,'off')
            xlim(app.UIAxes_3,xlimSubPlots)
                        
            % Plot the data
            updateplot(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)

            baseDirPath = pwd;
            while ~isfolder(fullfile(baseDirPath,'data'))
                [baseDirPath,~,~] = fileparts(baseDirPath);
            end

            % add the code in the entire directory 
            % (change path if code is in a different directory)
            addpath(genpath(baseDirPath))
            
            % load the file paths
            paths = loadpaths(baseDirPath,'saveFile','saveNewFile');
            dirPath = fullfile(paths.derivativesDirPath,"ecg_find_errors");

            readFilePath = fullfile(dirPath,'ecg_time_corrections.xls');

            bigTable = readtable(readFilePath);

            vars = bigTable.Properties.VariableNames;

            % Get new data
            newTableRow = app.UITable.Data;

            % Select a subset of the table array
            bigTable(app.RowIndexSpinner.Value,vars) = newTableRow;
            
            % Write new table
            writeFilePath = fullfile(dirPath,'ecg_time_corrections.xls');
            writetable(bigTable,writeFilePath)

            % Select a subset of the table array
            tableRow = bigTable(app.RowIndexSpinner.Value,vars);

            % Plot the original data
            currSub = tableRow.currSubs;
            currTime = tableRow.currTimes;
            [ibiData,ibiTime] = readecg(paths,currSub,'timeRef','ecg');
            [rawDataCh1,rawTime] = readecg(paths,currSub,'output','raw','chanNum',1,'timeRef','ecg');
            [rawDataCh2,~] = readecg(paths,currSub,'output','raw','chanNum',2,'timeRef','ecg');
            %%
            ecgFs = 1/median(diff(rawTime));

            xlimSubPlots = [currTime-10,currTime+10];

            plot(app.UIAxes,ibiTime,60./(ibiData./1000))
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,currTime,60/(ibiData(ibiTime==currTime)/1000),'LineWidth',3)
            xlim(app.UIAxes,xlimSubPlots)
            hold(app.UIAxes,'off')

            plot(app.UIAxes_2,rawTime,rawDataCh1)
            hold(app.UIAxes_2,'on')
            scatter(app.UIAxes_2,ibiTime,rawDataCh1(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            % hold(app.UIAxes_2,'on')
            % scatter(app.UIAxes_2,currTime,rawDataCh1(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_2,'off')
            xlim(app.UIAxes_2,xlimSubPlots)

            plot(app.UIAxes_3,rawTime,rawDataCh2)
            hold(app.UIAxes_3,'on')
            scatter(app.UIAxes_3,ibiTime,rawDataCh2(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            % hold(app.UIAxes_3,'on')
            % scatter(app.UIAxes_3,currTime,rawDataCh2(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_3,'off')
            xlim(app.UIAxes_3,xlimSubPlots)
                        
            % Plot the data
            updateplot(app);
            

        end

        % Value changed function: RowIndexSpinner
        function RowIndexSpinnerValueChanged(app, event)
            value = app.RowIndexSpinner.Value;

            baseDirPath = pwd;
            while ~isfolder(fullfile(baseDirPath,'data'))
                [baseDirPath,~,~] = fileparts(baseDirPath);
            end

            % add the code in the entire directory 
            % (change path if code is in a different directory)
            addpath(genpath(baseDirPath))
            
            % load the file paths
            paths = loadpaths(baseDirPath,'saveFile','saveNewFile');
            dirPath = fullfile(paths.derivativesDirPath,"ecg_find_errors");

            readFilePath = fullfile(dirPath,'ecg_time_corrections.xls');

            bigTable = readtable(readFilePath);
            vars = bigTable.Properties.VariableNames;

            % Select a subset of the table array
            if value > height(bigTable)
                app.RowIndexSpinner.Value = height(bigTable);
            end
            tableRow = bigTable(app.RowIndexSpinner.Value,vars);
            
            % Add data to the Table UI Component
            app.UITable.Data = tableRow;

            % Plot the original data
            currSub = tableRow.currSubs;
            currTime = tableRow.currTimes;
            [ibiData,ibiTime] = readecg(paths,currSub,'timeRef','ecg');
            [rawDataCh1,rawTime] = readecg(paths,currSub,'output','raw','chanNum',1,'timeRef','ecg');
            [rawDataCh2,~] = readecg(paths,currSub,'output','raw','chanNum',2,'timeRef','ecg');
            %%
            ecgFs = 1/median(diff(rawTime));

            xlimSubPlots = [currTime-10,currTime+10];

            plot(app.UIAxes,ibiTime,60./(ibiData./1000))
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,currTime,60/(ibiData(ibiTime==currTime)/1000),'LineWidth',3)
            xlim(app.UIAxes,xlimSubPlots)
            hold(app.UIAxes,'off')

            plot(app.UIAxes_2,rawTime,rawDataCh1)
            hold(app.UIAxes_2,'on')
            scatter(app.UIAxes_2,ibiTime,rawDataCh1(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            % hold(app.UIAxes_2,'on')
            % scatter(app.UIAxes_2,currTime,rawDataCh1(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_2,'off')
            xlim(app.UIAxes_2,xlimSubPlots)

            plot(app.UIAxes_3,rawTime,rawDataCh2)
            hold(app.UIAxes_3,'on')
            scatter(app.UIAxes_3,ibiTime,rawDataCh2(round((ibiTime - ibiTime(1) + 1).*ecgFs)))
            % hold(app.UIAxes_3,'on')
            % scatter(app.UIAxes_3,currTime,rawDataCh2(round((currTime - ibiTime(1) + 1).*ecgFs)),'LineWidth',3)
            hold(app.UIAxes_3,'off')
            xlim(app.UIAxes_3,xlimSubPlots)
                        
            % Plot the data
            updateplot(app);
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            
            baseDirPath = pwd;
            while ~isfolder(fullfile(baseDirPath,'data'))
                [baseDirPath,~,~] = fileparts(baseDirPath);
            end

            % add the code in the entire directory 
            % (change path if code is in a different directory)
            addpath(genpath(baseDirPath))
            
            % load the file paths
            paths = loadpaths(baseDirPath,'saveFile','saveNewFile');
            dirPath = fullfile(paths.derivativesDirPath,"ecg_find_errors");

            readFilePath = fullfile(dirPath,'ecg_time_corrections.xls');

            bigTable = readtable(readFilePath);

            vars = bigTable.Properties.VariableNames;

            % Get new data
            % set as evaluated
            app.UITable.Data.evaluated(1) = 1;
            newTableRow = app.UITable.Data;

            % Select a subset of the table array
            bigTable(app.RowIndexSpinner.Value,vars) = newTableRow;
            
            % Write new table
            writeFilePath = fullfile(dirPath,'ecg_time_corrections.xls');
            writetable(bigTable,writeFilePath)


            updateplot(app)
        end

        % Button pushed function: DuplicateRowButton
        function DuplicateRowButtonPushed(app, event)
            baseDirPath = pwd;
            while ~isfolder(fullfile(baseDirPath,'data'))
                [baseDirPath,~,~] = fileparts(baseDirPath);
            end

            % add the code in the entire directory 
            % (change path if code is in a different directory)
            addpath(genpath(baseDirPath))
            
            % load the file paths
            paths = loadpaths(baseDirPath,'saveFile','saveNewFile');
            dirPath = fullfile(paths.derivativesDirPath,"ecg_find_errors");

            readFilePath = fullfile(dirPath,'ecg_time_corrections.xls');

            bigTable = readtable(readFilePath);

            vars = bigTable.Properties.VariableNames;

            % Select a subset of the table array
            newBigTable = array2table(NaN(length(vars),height(bigTable)+1),'VariableNames',vars);
            newBigTable(1:app.RowIndexSpinner.Value,:) = bigTable(1:app.RowIndexSpinner.Value,:); 
            newBigTable(app.RowIndexSpinner.Value + 1: height(bigTable)+1,vars) = ...
                bigTable(app.RowIndexSpinner.Value: height(bigTable),vars);

            app.RowIndexSpinner.Value = app.RowIndexSpinner.Value + 1;
            
            bigTable = newBigTable;

            % Write new table
            writeFilePath = fullfile(dirPath,'ecg_time_corrections.xls');
            writetable(bigTable,writeFilePath)

            updateplot(app)

        end

        % Value changed function: BPMDisplayDropDown
        function BPMDisplayDropDownValueChanged(app, event)
            value = Saved IbiSaved Ibi;
            updateplot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EcgErrorAnalyisisUIFigure = uifigure('Visible', 'off');
            app.EcgErrorAnalyisisUIFigure.Name = 'ECG Error Analyisis';
            app.UIAxes = uiaxes(app.EcgErrorAnalyisisUIFigure);
            title(app.UIAxes, 'Original BPM')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'BPM')
            app.UIAxes_2 = uiaxes(app.EcgErrorAnalyisisUIFigure);
            title(app.UIAxes_2, 'Original Raw Ch1')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amp')
            app.UIAxes_3 = uiaxes(app.EcgErrorAnalyisisUIFigure);
            title(app.UIAxes_3, 'Original Raw Ch2')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Amp')
            app.UIAxes_4 = uiaxes(app.EcgErrorAnalyisisUIFigure);
            title(app.UIAxes_4, 'Updated BPM')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'BPM')
            app.UIAxes_5 = uiaxes(app.EcgErrorAnalyisisUIFigure);
            title(app.UIAxes_5, 'Updated Raw Ch1')
            xlabel(app.UIAxes_5, 'Time')
            ylabel(app.UIAxes_5, 'Amp')
            app.UIAxes_6 = uiaxes(app.EcgErrorAnalyisisUIFigure);
            title(app.UIAxes_6, 'Updated Raw Ch2')
            xlabel(app.UIAxes_6, 'Time')
            ylabel(app.UIAxes_6, 'Amp')
            app.UITable = uitable(app.EcgErrorAnalyisisUIFigure);
            app.UITable.ColumnName = {'Participant'; 'Time of outlier'; 'Time of beat to remove'; 'Time of beat to add'; 'Time of unreliable beat'; 'Evaluated'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false false false false];
            app.UITable.ColumnEditable = [false false true true true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RowIndexSpinnerLabel = uilabel(app.EcgErrorAnalyisisUIFigure);
            app.RowIndexSpinnerLabel.Text = 'Row Index';
            app.RowIndexSpinner = uispinner(app.EcgErrorAnalyisisUIFigure);
            app.RowIndexSpinner.Limits = [1 Inf];
            app.RowIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @RowIndexSpinnerValueChanged, true);
            app.RowIndexSpinner.Value = 1;
            app.EvaluateButton = uibutton(app.EcgErrorAnalyisisUIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.DuplicateRowButton = uibutton(app.EcgErrorAnalyisisUIFigure, 'push');
            app.DuplicateRowButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateRowButtonPushed, true);
            app.DuplicateRowButton.Text = 'Duplicate Row';
            app.BPMDisplayDropDownLabel = uilabel(app.EcgErrorAnalyisisUIFigure);
            app.BPMDisplayDropDownLabel.Text = 'BPM Display';
            app.BPMDisplayDropDown = uidropdown(app.EcgErrorAnalyisisUIFigure);
            app.BPMDisplayDropDown.Items = {'Saved Ibi', 'Diff IbiTime'};
            app.BPMDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @BPMDisplayDropDownValueChanged, true);
            app.BPMDisplayDropDown.Value = 'Saved Ibi';
            % Show the figure after all components are created
            app.EcgErrorAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableDataApp4Ecg2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EcgErrorAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EcgErrorAnalyisisUIFigure)
        end
    end
end